classdef simulator < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MortgageCalculatorUIFigure   matlab.ui.Figure
        GridLayout                   matlab.ui.container.GridLayout
        LeftPanel                    matlab.ui.container.Panel
        SampleSizeEditFieldLabel     matlab.ui.control.Label
        SampleSizeEditField          matlab.ui.control.NumericEditField
        DeathRateEditFieldLabel      matlab.ui.control.Label
        SimulationButton             matlab.ui.control.Button
        PoolTestingDropDownLabel     matlab.ui.control.Label
        DeathRateEditField           matlab.ui.control.NumericEditField
        PoolTestingDropDown          matlab.ui.control.DropDown
        VaccinationRatioSliderLabel  matlab.ui.control.Label
        VaccinationRatioSlider       matlab.ui.control.Slider
        StayAtHomeSwitchLabel        matlab.ui.control.Label
        StayAtHomeSwitch             matlab.ui.control.Switch
        RightPanel                   matlab.ui.container.Panel
        PrincipalInterestUIAxes      matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.MortgageCalculatorUIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {316, 316};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {273, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end

        % Button pushed function: SimulationButton
        function SimulationButtonPushed(app, event)
            cla(app.PrincipalInterestUIAxes)
            human=200;
            sample.x=rand(human,1);
            sample.y=rand(human,1);
            sample.u=(randi([-1 0],human,1)*2+1).*rand(human,1);
            sample.v=(randi([-1 0],human,1)*2+1).*rand(human,1);
            sample.sick=0*eye(human,1);
            sample.sick_time=0*eye(human,1);
            dt=0.02;
            i=0;
            death_rate=0.02;
            temp=randi([1 human],1);
            sample.sick(temp)=1; %Intorducing a random sick
            
            while ~isempty(find(sample.sick==1))
                if i == 0
                    for k = 1 : human
                        hold(app.PrincipalInterestUIAxes,'on');
                        switch sample.sick(k)
                            case 0 %Healthy
                                plot(app.PrincipalInterestUIAxes,sample.x(k), sample.y(k), '.b','MarkerSize',20);
                            case 1 %Sick
                                plot(app.PrincipalInterestUIAxes,sample.x(k), sample.y(k), '.r','MarkerSize',20);
                            case 2 %Recovered
                                plot(app.PrincipalInterestUIAxes,sample.x(k), sample.y(k), '.g','MarkerSize',20);
                            case 3 %Dead
                                plot(app.PrincipalInterestUIAxes,sample.x(k), sample.y(k), '.k','MarkerSize',20);
                        end
                        hold(app.PrincipalInterestUIAxes,'off');
                    end
                else
                    h = findobj(app.PrincipalInterestUIAxes,'Type','line');
                    for k=1:human
                        if sample.sick(k)~=3
                            x=sample.x(k)+sample.u(k)*dt;
                            y=sample.y(k)+sample.v(k)*dt;
                        else
                            x=sample.x(k);
                            y=sample.y(k);
                        end
                        if x > 1-dt
                            sample.x(k) = sample.x(k)*(1-dt);
                            sample.u(k) = -sample.u(k);
                        end
                        if y > 1-dt
                            sample.y(k) = sample.y(k)*(1-dt);
                            sample.v(k) = -sample.v(k);
                        end
                        if x < -dt
                            sample.x(k) = sample.x(k)*(1-dt);
                            sample.u(k) = -sample.u(k);
                        end
                        if y < -dt
                            sample.y(k) = sample.y(k)*(1-dt);
                            sample.v(k) = -sample.v(k);
                        end
                        sample.x(k)=x;
                        sample.y(k)=y;
                        if sample.sick(k)==1
                            for jj=1:human
                                if k~=jj &&  norm([sample.x(jj) sample.y(jj)]-[sample.x(k) sample.y(k)])<=0.01 && sample.sick(jj)==0
                                    sample.sick(jj)=1;
                                    sample.sick_time(jj)= i*dt;
                                end
                            end
                        end
                        
                        for ii=1:human
                            if dt*i-sample.sick_time(ii)==0.5 && sample.sick(ii)==1 && dt*i>=1
                                if rand<=death_rate
                                    sample.sick(ii)=3;
                                else
                                    sample.sick(ii)=2;
                                end
                            end
                        end
                        
                        switch sample.sick(k)
                            case 0 %Healthy
                                set(h(k),'XData',x,'YData',y,'Color','b')
                            case 1 %Sick
                                set(h(k),'XData',x,'YData',y,'Color','r')
                            case 2 %Recovered
                                set(h(k),'XData',x,'YData',y,'Color','g')
                            case 3 %Dead
                                set(h(k),'XData',x,'YData',y,'Color','k')
                        end
                    end
                    drawnow
                end
                i=i+1;
            end
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MortgageCalculatorUIFigure = uifigure('Visible', 'off');
            app.MortgageCalculatorUIFigure.Name = 'Mortgage Calculator';
            app.MortgageCalculatorUIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.MortgageCalculatorUIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.SampleSizeEditFieldLabel = uilabel(app.LeftPanel);
            app.SampleSizeEditFieldLabel.Text = 'Sample Size';
            app.SampleSizeEditField = uieditfield(app.LeftPanel, 'numeric');
            app.SampleSizeEditField.Limits = [0 10000000];
            app.SampleSizeEditField.ValueDisplayFormat = '%8.f';
            app.SampleSizeEditField.Value = 200;
            app.DeathRateEditFieldLabel = uilabel(app.LeftPanel);
            app.DeathRateEditFieldLabel.Text = 'Death Rate';
            app.SimulationButton = uibutton(app.LeftPanel, 'push');
            app.SimulationButton.ButtonPushedFcn = createCallbackFcn(app, @SimulationButtonPushed, true);
            app.SimulationButton.Text = 'Simulation';
            app.PoolTestingDropDownLabel = uilabel(app.LeftPanel);
            app.PoolTestingDropDownLabel.Text = 'Pool Testing';
            app.DeathRateEditField = uieditfield(app.LeftPanel, 'numeric');
            app.DeathRateEditField.Limits = [0.001 100];
            app.DeathRateEditField.Value = 0.02;
            app.PoolTestingDropDown = uidropdown(app.LeftPanel);
            app.PoolTestingDropDown.Items = {'None', 'Batch Testing', 'Two-pass Testing', 'Single-pass Testing'};
            app.PoolTestingDropDown.Value = 'None';
            app.VaccinationRatioSliderLabel = uilabel(app.LeftPanel);
            app.VaccinationRatioSliderLabel.Text = 'Vaccination Ratio';
            app.VaccinationRatioSlider = uislider(app.LeftPanel);
            app.StayAtHomeSwitchLabel = uilabel(app.LeftPanel);
            app.StayAtHomeSwitchLabel.Text = 'Stay At Home';
            app.StayAtHomeSwitch = uiswitch(app.LeftPanel, 'slider');
            app.RightPanel = uipanel(app.GridLayout);
            app.PrincipalInterestUIAxes = uiaxes(app.RightPanel);
            title(app.PrincipalInterestUIAxes, 'Simulation')
            app.PrincipalInterestUIAxes.XLim = [-0.05 1.05];
            app.PrincipalInterestUIAxes.YLim = [-0.05 1.05];
            % Show the figure after all components are created
            app.MortgageCalculatorUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = simulator

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MortgageCalculatorUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MortgageCalculatorUIFigure)
        end
    end
end